classdef coax_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ConductorMaterialDropDownLabel  matlab.ui.control.Label
        ConductorMaterialDropDown       matlab.ui.control.DropDown
        DielectricMaterialDropDownLabel  matlab.ui.control.Label
        DielectricMaterialDropDown      matlab.ui.control.DropDown
        InnerRadiusofConductormmEditFieldLabel  matlab.ui.control.Label
        InnerRadiusofConductormmEditField  matlab.ui.control.NumericEditField
        OuterRadiusofConductormmEditFieldLabel  matlab.ui.control.Label
        OuterRadiusofConductormmEditField  matlab.ui.control.NumericEditField
        RealLoadImpedanceOhmsEditFieldLabel  matlab.ui.control.Label
        RealLoadImpedanceOhmsEditField  matlab.ui.control.NumericEditField
        ImaginaryLoadImpedanceOhmsEditFieldLabel  matlab.ui.control.Label
        ImaginaryLoadImpedanceOhmsEditField  matlab.ui.control.NumericEditField
        CableLengthmEditFieldLabel      matlab.ui.control.Label
        CableLengthmEditField           matlab.ui.control.NumericEditField
        SignalFrequencykHzEditFieldLabel  matlab.ui.control.Label
        SignalFrequencykHzEditField     matlab.ui.control.NumericEditField
        GenerateButton                  matlab.ui.control.Button
        ConductorConductanceSmEditFieldLabel  matlab.ui.control.Label
        ConductorConductanceSmEditField  matlab.ui.control.EditField
        RelativePermeabilityEditFieldLabel  matlab.ui.control.Label
        RelativePermeabilityEditField   matlab.ui.control.EditField
        RelativePermittivityEditFieldLabel  matlab.ui.control.Label
        RelativePermittivityEditField   matlab.ui.control.EditField
        DielectricStrengthVmEditFieldLabel  matlab.ui.control.Label
        DielectricStrengthVmEditField   matlab.ui.control.EditField
        DielectricConductanceEditFieldLabel  matlab.ui.control.Label
        DielectricConductanceEditField  matlab.ui.control.EditField
    end

    
    properties (Access = private)
        
        % Values to pull from user-entered parameters
        innerRadius
        outerRadius
        realLoadImpedance
        imaginaryLoadImpedance
        cableLength
        signalFrequency
        radSignalFrequency         
        
        % Physical Conductor Constants
        sig_C
        mu_R
        mu_0 = 4*pi*1e-7;
        
        % Physical Dielectric Constants
        er
        Ebr
        sig_D
        e_0 = 8.85e-12;
        
        %Values to calculate on backend
        R_dist
        L_dist
        G_dist
        C_dist
        gamma
        alpha
        beta
        Z_0
        refCoeff
        VSWR
        Z_in
        f_cut
        V_br
        
    end
    
    methods (Access = private)
        
        function generateRLGC(app)
            app.R_dist = (1/(2*pi))*(1/(app.innerRadius) + 1/(app.outerRadius)) * sqrt(pi*app.signalFrequency*(app.mu_R * app.mu_0)/app.sig_C);
            disp(app.R_dist);
            app.L_dist = (app.mu_R * app.mu_0)/(2*pi)*log(app.outerRadius/app.innerRadius);
            disp(app.L_dist);
            app.G_dist = 2*pi*app.sig_D/log(app.outerRadius/app.innerRadius);    
            disp(app.G_dist);
            app.C_dist = 2*pi*app.er*app.e_0/log(app.outerRadius/app.innerRadius);
            disp(app.C_dist);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            
            generateRLGC(app);
            
            %h = tf([1 1],[1 2 1]);
            %bode(h)
        end

        % Value changed function: InnerRadiusofConductormmEditField
        function InnerRadiusofConductormmEditFieldValueChanged(app, event)
            value = app.InnerRadiusofConductormmEditField.Value;
            app.innerRadius = value * 1e-3;
        end

        % Value changed function: OuterRadiusofConductormmEditField
        function OuterRadiusofConductormmEditFieldValueChanged(app, event)
            value = app.OuterRadiusofConductormmEditField.Value;
            app.outerRadius = value * 1e-3;
        end

        % Value changed function: RealLoadImpedanceOhmsEditField
        function RealLoadImpedanceOhmsEditFieldValueChanged(app, event)
            value = app.RealLoadImpedanceOhmsEditField.Value;
            app.realLoadImpedance = value;
        end

        % Value changed function: 
        % ImaginaryLoadImpedanceOhmsEditField
        function ImaginaryLoadImpedanceOhmsEditFieldValueChanged(app, event)
            value = app.ImaginaryLoadImpedanceOhmsEditField.Value;
            app.imaginaryLoadImpedance = value;
        end

        % Value changed function: CableLengthmEditField
        function CableLengthmEditFieldValueChanged(app, event)
            value = app.CableLengthmEditField.Value;
            app.cableLength = value;
        end

        % Value changed function: SignalFrequencykHzEditField
        function SignalFrequencykHzEditFieldValueChanged(app, event)
            value = app.SignalFrequencykHzEditField.Value;
            app.signalFrequency = value * 1e3;
            app.radSignalFrequency = app.signalFrequency * 2 * pi;
        end

        % Value changed function: ConductorMaterialDropDown
        function ConductorMaterialDropDownValueChanged(app, event)
            
            % Pull parameters from itemsData array 
            itemData = app.ConductorMaterialDropDown.Value;
            
            %Split string into values and get rid of delimiters
            splitCond = split(itemData, ' ');
            intCondValueStr = splitCond{1, 1};
            intCondValueStr = erase(intCondValueStr, '[');
            intPermValueStr = splitCond{2, 1};
            intPermValueStr = erase(intPermValueStr, ']');
            
            %Convert strings to numbers (for backend calculations) and
            %engineering notation (for display) 
            app.sig_C = str2double(intCondValueStr);
            app.mu_R = str2double(intPermValueStr);
            sig_C_ENG = num2eng(app.sig_C, 1, 1);
            mu_R_ENG = num2eng(app.mu_R, 1, 1);
            
            %Display entered values to user
            app.ConductorConductanceSmEditField.Value = num2str(sig_C_ENG);
            app.RelativePermeabilityEditField.Value = num2str(mu_R_ENG);
            
        end

        % Value changed function: DielectricMaterialDropDown
        function DielectricMaterialDropDownValueChanged(app, event)
            
            % Pull parameters from itemsData array 
            itemData = app.DielectricMaterialDropDown.Value;
            
            %Split string into values and get rid of delimiters
            splitDiel = split(itemData, ' ');
            intCondValueStr = splitDiel{3, 1};
            intCondValueStr = erase(intCondValueStr, ']');
            intPermValueStr = splitDiel{1, 1};
            intPermValueStr = erase(intPermValueStr, '[');
            intBreakValueStr = splitDiel{2, 1};
            
            %Convert strings to numbers (for backend calculations) and
            %engineering notation (for display) 
            app.sig_D = str2double(intCondValueStr);
            app.er = str2double(intPermValueStr);
            app.Ebr = str2double(intBreakValueStr);
            sig_D_ENG = num2eng(app.sig_D, 1, 1);
            er_ENG = num2eng(app.er, 1, 1);
            Ebr_ENG = num2eng(app.sig_D, 1, 1);
            
            %Display entered values to user
            app.DielectricConductanceEditField.Value = num2str(sig_D_ENG);
            app.RelativePermittivityEditField.Value = num2str(er_ENG);
            app.DielectricStrengthVmEditField.Value = num2str(Ebr_ENG);
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConductorMaterialDropDownLabel = uilabel(app.UIFigure);
            app.ConductorMaterialDropDownLabel.Text = 'Conductor Material';
            app.ConductorMaterialDropDown = uidropdown(app.UIFigure);
            app.ConductorMaterialDropDown.Items = {'Aluminum', 'Carbon', 'Copper', 'Gold', 'Graphite', 'Iron (99.8%)', 'Iron (99.96%)', 'Lead', 'Nichrome', 'Nickel', 'Silver', 'Solder', 'Stainless Steel', 'Tin', 'Tungsten'};
            app.ConductorMaterialDropDown.ItemsData = {'[38000000 1.0002]', '[30000 1]', '[58000000 0.999991]', '[41000000 0.99986]', '[70000 1]', '[10000000 5000]', '[10000000 280000]', '[5000000 1]', '[1000000 1]', '[15000000 600]', '[62000000 0.99998]', '[7000000 1]', '[1100000 1]', '[8800000 1]', '[18000000 1]'};
            app.ConductorMaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @ConductorMaterialDropDownValueChanged, true);
            app.ConductorMaterialDropDown.Value = '[30000 1]';
            app.DielectricMaterialDropDownLabel = uilabel(app.UIFigure);
            app.DielectricMaterialDropDownLabel.Text = 'Dielectric Material';
            app.DielectricMaterialDropDown = uidropdown(app.UIFigure);
            app.DielectricMaterialDropDown.Items = {'Air', 'Alumina', 'Bbarium Titanate', 'Glass', 'Mica', 'Polyethylene', 'Polystyrene', 'Quartz', 'Silicon', 'Soil', 'Teflon', 'Water', 'Seawater'};
            app.DielectricMaterialDropDown.ItemsData = {'[1.0005 3000000 0]', '[9.9 0 0]', '[1200 7500000 0]', '[10 30000000 0.000000000001]', '[4.2 0 0]', '[5.4 200000000 0.000000000000001]', '[2.26 47000000 0.0000000000000001]', '[2.56 20000000 0.00000000000000001]', '[3.8 30000000 0.00000000000000001]', '[11.8 0 0.00044]', '[3.5 0 0.002]', '[2.1 60000000 0.000000000000001]', '[81 0 0.0001]', '[72 0 5]'};
            app.DielectricMaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @DielectricMaterialDropDownValueChanged, true);
            app.DielectricMaterialDropDown.Value = '[1.0005 3000000 0]';
            app.InnerRadiusofConductormmEditFieldLabel = uilabel(app.UIFigure);
            app.InnerRadiusofConductormmEditFieldLabel.Text = 'Inner Radius of Conductor (mm)';
            app.InnerRadiusofConductormmEditField = uieditfield(app.UIFigure, 'numeric');
            app.InnerRadiusofConductormmEditField.Limits = [0 Inf];
            app.InnerRadiusofConductormmEditField.ValueChangedFcn = createCallbackFcn(app, @InnerRadiusofConductormmEditFieldValueChanged, true);
            app.OuterRadiusofConductormmEditFieldLabel = uilabel(app.UIFigure);
            app.OuterRadiusofConductormmEditFieldLabel.Text = 'Outer Radius of Conductor (mm)';
            app.OuterRadiusofConductormmEditField = uieditfield(app.UIFigure, 'numeric');
            app.OuterRadiusofConductormmEditField.Limits = [0 Inf];
            app.OuterRadiusofConductormmEditField.ValueChangedFcn = createCallbackFcn(app, @OuterRadiusofConductormmEditFieldValueChanged, true);
            app.RealLoadImpedanceOhmsEditFieldLabel = uilabel(app.UIFigure);
            app.RealLoadImpedanceOhmsEditFieldLabel.Text = ' Real Load Impedance (Ohms)';
            app.RealLoadImpedanceOhmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.RealLoadImpedanceOhmsEditField.Limits = [0 Inf];
            app.RealLoadImpedanceOhmsEditField.ValueChangedFcn = createCallbackFcn(app, @RealLoadImpedanceOhmsEditFieldValueChanged, true);
            app.ImaginaryLoadImpedanceOhmsEditFieldLabel = uilabel(app.UIFigure);
            app.ImaginaryLoadImpedanceOhmsEditFieldLabel.Text = 'Imaginary Load Impedance (Ohms)';
            app.ImaginaryLoadImpedanceOhmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImaginaryLoadImpedanceOhmsEditField.ValueChangedFcn = createCallbackFcn(app, @ImaginaryLoadImpedanceOhmsEditFieldValueChanged, true);
            app.CableLengthmEditFieldLabel = uilabel(app.UIFigure);
            app.CableLengthmEditFieldLabel.Text = 'Cable Length (m)';
            app.CableLengthmEditField = uieditfield(app.UIFigure, 'numeric');
            app.CableLengthmEditField.Limits = [0 Inf];
            app.CableLengthmEditField.ValueChangedFcn = createCallbackFcn(app, @CableLengthmEditFieldValueChanged, true);
            app.SignalFrequencykHzEditFieldLabel = uilabel(app.UIFigure);
            app.SignalFrequencykHzEditFieldLabel.Text = 'Signal Frequency (kHz)';
            app.SignalFrequencykHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.SignalFrequencykHzEditField.Limits = [0 Inf];
            app.SignalFrequencykHzEditField.ValueChangedFcn = createCallbackFcn(app, @SignalFrequencykHzEditFieldValueChanged, true);
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.ConductorConductanceSmEditFieldLabel = uilabel(app.UIFigure);
            app.ConductorConductanceSmEditFieldLabel.Text = 'Conductor Conductance (S/m)';
            app.ConductorConductanceSmEditField = uieditfield(app.UIFigure, 'text');
            app.ConductorConductanceSmEditField.Editable = 'off';
            app.RelativePermeabilityEditFieldLabel = uilabel(app.UIFigure);
            app.RelativePermeabilityEditFieldLabel.Text = 'Relative Permeability';
            app.RelativePermeabilityEditField = uieditfield(app.UIFigure, 'text');
            app.RelativePermeabilityEditField.Editable = 'off';
            app.RelativePermittivityEditFieldLabel = uilabel(app.UIFigure);
            app.RelativePermittivityEditFieldLabel.Text = 'Relative Permittivity';
            app.RelativePermittivityEditField = uieditfield(app.UIFigure, 'text');
            app.RelativePermittivityEditField.Editable = 'off';
            app.DielectricStrengthVmEditFieldLabel = uilabel(app.UIFigure);
            app.DielectricStrengthVmEditFieldLabel.Text = 'Dielectric Strength (V/m)';
            app.DielectricStrengthVmEditField = uieditfield(app.UIFigure, 'text');
            app.DielectricStrengthVmEditField.Editable = 'off';
            app.DielectricConductanceEditFieldLabel = uilabel(app.UIFigure);
            app.DielectricConductanceEditFieldLabel.Text = 'Dielectric Conductance';
            app.DielectricConductanceEditField = uieditfield(app.UIFigure, 'text');
            app.DielectricConductanceEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = coax_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end